classdef ThermalCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        thermalCalculator             matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        parameters                    matlab.ui.container.Panel
        parametersTab                 matlab.ui.container.TabGroup
        statorParameters              matlab.ui.container.Tab
        OuterDiaEditFieldLabel        matlab.ui.control.Label
        statorOuterDiameter           matlab.ui.control.NumericEditField
        InnerDiaLabel                 matlab.ui.control.Label
        statorInnerDiameter           matlab.ui.control.NumericEditField
        StackHeightLabel              matlab.ui.control.Label
        statorStackHeight             matlab.ui.control.NumericEditField
        NumberofSlotsEditFieldLabel   matlab.ui.control.Label
        statorNumOfSlots              matlab.ui.control.NumericEditField
        ToothWidthLabel               matlab.ui.control.Label
        statorToothWidth              matlab.ui.control.NumericEditField
        SlotOpeningWidthLabel         matlab.ui.control.Label
        statorSlotOpeningWidth        matlab.ui.control.NumericEditField
        YokeWidthLabel                matlab.ui.control.Label
        statorYokeWidth               matlab.ui.control.NumericEditField
        ToothShoeDepthEditFieldLabel  matlab.ui.control.Label
        statorToothShoeDepth          matlab.ui.control.NumericEditField
        ToothTangHeightLabel          matlab.ui.control.Label
        statorToothTangHeight         matlab.ui.control.NumericEditField
        rotorParameters               matlab.ui.container.Tab
        OuterDiaEditFieldLabel_2      matlab.ui.control.Label
        rotorOuterDiameter            matlab.ui.control.NumericEditField
        InnerDiaLabel_2               matlab.ui.control.Label
        rotorYokeInnerDiameter        matlab.ui.control.NumericEditField
        NumberofPolesLabel            matlab.ui.control.Label
        rotorNumOfPoles               matlab.ui.control.NumericEditField
        MagnetWidthLabel              matlab.ui.control.Label
        rotorMagnetWidth              matlab.ui.control.NumericEditField
        MagnetThicknessLabel          matlab.ui.control.Label
        rotorMagnetThickness          matlab.ui.control.NumericEditField
        MagnetOuterArcRadiusLabel     matlab.ui.control.Label
        rotorMagnetOuterArcRadius     matlab.ui.control.NumericEditField
        shafting                      matlab.ui.container.Tab
        OuterDiaEditFieldLabel_3      matlab.ui.control.Label
        rotorOuterDiameter_2          matlab.ui.control.NumericEditField
        InnerDiaLabel_3               matlab.ui.control.Label
        rotorYokeInnerDiameter_2      matlab.ui.control.NumericEditField
        NumberofPolesLabel_2          matlab.ui.control.Label
        rotorNumOfPoles_2             matlab.ui.control.NumericEditField
        MagnetWidthLabel_2            matlab.ui.control.Label
        rotorMagnetWidth_2            matlab.ui.control.NumericEditField
        MagnetThicknessLabel_2        matlab.ui.control.Label
        rotorMagnetThickness_2        matlab.ui.control.NumericEditField
        CaseTab                       matlab.ui.container.Tab
        OuterDiaEditFieldLabel_4      matlab.ui.control.Label
        rotorOuterDiameter_3          matlab.ui.control.NumericEditField
        InnerDiaLabel_4               matlab.ui.control.Label
        rotorYokeInnerDiameter_3      matlab.ui.control.NumericEditField
        NumberofPolesLabel_3          matlab.ui.control.Label
        rotorNumOfPoles_3             matlab.ui.control.NumericEditField
        MagnetWidthLabel_3            matlab.ui.control.Label
        rotorMagnetWidth_3            matlab.ui.control.NumericEditField
        MagnetThicknessLabel_3        matlab.ui.control.Label
        rotorMagnetThickness_3        matlab.ui.control.NumericEditField
        operationParameters           matlab.ui.container.Tab
        lossParameters                matlab.ui.container.Tab
        results                       matlab.ui.container.Panel
        resultsTab                    matlab.ui.container.TabGroup
        laminationStructure           matlab.ui.container.Tab
        laminationStructurePlot       matlab.ui.control.UIAxes
        axialStructure                matlab.ui.container.Tab
        axialStructurePlot            matlab.ui.control.UIAxes
        thermalResistance             matlab.ui.container.Tab
        ApplyParameters               matlab.ui.control.Button
        Run                           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function [coordinateParaTrans_1,coordinateParaTrans_2] = coordinateTransformation (~,coordinatePara_1,coordinatePara_2,transMethod)
            %coordinateTransformation 用于平面极坐标与直角坐标系间的变换
            %   输入：原始坐标系参数与变换方式；
            %   输出：变换后坐标系；
            %   角度单位为弧度
            %   输入中的transMethod定义如下：
            %       "rec2polar" 将直角坐标系变为极坐标系
            %       "polar2rec" 将极坐标变为直角坐标系
            switch transMethod
                case {'rec2polar'}
                    coordinateParaTrans_1 = sqrt( coordinatePara_1.^2 + coordinatePara_2.^2 );
                    coordinateParaTrans_2 = atan( coordinatePara_2 ./ coordinatePara_1 );
                case {'polar2rec'}
                    coordinateParaTrans_1 = coordinatePara_1 .* cos( coordinatePara_2 );
                    coordinateParaTrans_2 = coordinatePara_1 .* sin( coordinatePara_2 );
            end
        end 
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyParameters
        function ApplyParametersButtonPushed(app, event)
            %%绘制冲片视图定子外沿
            alphaStatorOuter = 0:pi/200:2*pi;
            rStatorOuter = zeros(1,length(alphaStatorOuter)) + app.statorOuterDiameter.Value/2;
            [xStatorOuter,yStatorOuter] = coordinateTransformation (app,rStatorOuter,alphaStatorOuter,'polar2rec');
            plot(app.laminationStructurePlot, xStatorOuter, yStatorOuter, "LineWidth", 2, "Color", "k", "LineStyle", "-");
            hold(app.laminationStructurePlot,'on');
            %%绘制冲片视图定子内沿
            rStatorYokeInner = zeros(1,201) + app.statorOuterDiameter.Value/2 - app.statorYokeWidth.Value;
            yStatorToothOuter = app.statorToothWidth.Value/2;
            xStatorToothOuter = sqrt( rStatorYokeInner(1)^2 - yStatorToothOuter(1)^2 );
            alphaStatorYokeInner = atan(yStatorToothOuter/xStatorToothOuter):(pi/app.statorNumOfSlots.Value-atan(yStatorToothOuter/xStatorToothOuter))/200:pi/app.statorNumOfSlots.Value;
            alphaStatorYokeInnerMir = -alphaStatorYokeInner;
            yStatorToothInner = yStatorToothOuter;
            xStatorToothInner = app.statorInnerDiameter.Value/2 + app.statorToothShoeDepth.Value;
            [rStatorToothOuter,alphaStatorToothOuter] = coordinateTransformation(app, xStatorToothOuter, yStatorToothOuter, 'rec2polar');
            [rStatorToothInner,alphaStatorToothInner] = coordinateTransformation(app, xStatorToothInner, yStatorToothInner, 'rec2polar');
            alphaStatorToothOuterMir = -alphaStatorToothOuter;
            alphaStatorToothInnerMir = -alphaStatorToothInner;
            rStatorToothShoeInner = zeros(1,201) + app.statorInnerDiameter.Value/2;
            thetaStatorToothShoe = 2*(pi/app.statorNumOfSlots.Value - asin(app.statorSlotOpeningWidth.Value/app.statorInnerDiameter.Value));
            alphaStatorToothShoeInner = -thetaStatorToothShoe/2:thetaStatorToothShoe/200:thetaStatorToothShoe/2;
            rStatorToothTangInner = app.statorInnerDiameter.Value/2;
            alphaStatorToothTangInner = alphaStatorToothShoeInner(end);
            alphaStatorToothTangOuter = pi/app.statorNumOfSlots.Value - atan(app.statorSlotOpeningWidth.Value/2/(app.statorToothTangHeight.Value+rStatorToothTangInner*cos(pi/app.statorNumOfSlots.Value-alphaStatorToothTangInner)));
            rStatorToothTangOuter = (app.statorSlotOpeningWidth.Value/2)/sin(pi/app.statorNumOfSlots.Value-alphaStatorToothTangOuter);
            alphaStatorToothTangInnerMir = -alphaStatorToothTangInner;
            alphaStatorToothTangOuterMir = -alphaStatorToothTangOuter;
            for i = 1:1:app.statorNumOfSlots.Value
                [xStatorYokeInner,yStatorYokeInner] = coordinateTransformation (app, rStatorYokeInner, alphaStatorYokeInner, 'polar2rec');
                plot(app.laminationStructurePlot, xStatorYokeInner,yStatorYokeInner,"LineWidth",2,"Color","k","LineStyle","-");
                [xStatorYokeInnerMir,yStatorYokeInnerMir] = coordinateTransformation (app, rStatorYokeInner, alphaStatorYokeInnerMir, 'polar2rec');
                plot(app.laminationStructurePlot, xStatorYokeInnerMir,yStatorYokeInnerMir,"LineWidth",2,"Color","k","LineStyle","-");
                alphaStatorYokeInner = alphaStatorYokeInner + 2*pi/app.statorNumOfSlots.Value;
                alphaStatorYokeInnerMir = alphaStatorYokeInnerMir + 2*pi/app.statorNumOfSlots.Value;
                [xStatorToothOuter,yStatorToothOuter] = coordinateTransformation(app, rStatorToothOuter, alphaStatorToothOuter, 'polar2rec');
                [xStatorToothInner,yStatorToothInner] = coordinateTransformation(app, rStatorToothInner, alphaStatorToothInner, 'polar2rec');
                line(app.laminationStructurePlot, [xStatorToothOuter, xStatorToothInner], [yStatorToothOuter, yStatorToothInner], "LineWidth",2,"Color","k","LineStyle","-");
                [xStatorToothOuterMir,yStatorToothOuterMir] = coordinateTransformation(app, rStatorToothOuter, alphaStatorToothOuterMir, 'polar2rec');
                [xStatorToothInnerMir,yStatorToothInnerMir] = coordinateTransformation(app, rStatorToothInner, alphaStatorToothInnerMir, 'polar2rec');
                line(app.laminationStructurePlot, [xStatorToothOuterMir, xStatorToothInnerMir], [yStatorToothOuterMir, yStatorToothInnerMir], "LineWidth",2,"Color","k","LineStyle","-");
                alphaStatorToothInner = alphaStatorToothInner + 2*pi/app.statorNumOfSlots.Value;
                alphaStatorToothInnerMir =alphaStatorToothInnerMir + 2*pi/app.statorNumOfSlots.Value;
                alphaStatorToothOuter = alphaStatorToothOuter + 2*pi/app.statorNumOfSlots.Value;
                alphaStatorToothOuterMir = alphaStatorToothOuterMir + 2*pi/app.statorNumOfSlots.Value;
                [xStatorToothShoeInner,yStatorToothShoeInner] = coordinateTransformation(app, rStatorToothShoeInner, alphaStatorToothShoeInner, 'polar2rec');
                plot(app.laminationStructurePlot, xStatorToothShoeInner, yStatorToothShoeInner, "LineWidth",2,"Color","k","LineStyle","-");
                alphaStatorToothShoeInner = alphaStatorToothShoeInner + 2*pi/app.statorNumOfSlots.Value;
                [xStatorToothTangInner,yStatorToothTangInner] = coordinateTransformation(app, rStatorToothTangInner,alphaStatorToothTangInner,'polar2rec');
                [xStatorToothTangOuter,yStatorToothTangOuter] = coordinateTransformation(app, rStatorToothTangOuter,alphaStatorToothTangOuter,'polar2rec');
                [xStatorToothTangInnerMir,yStatorToothTangInnerMir] = coordinateTransformation(app, rStatorToothTangInner,alphaStatorToothTangInnerMir,'polar2rec');
                [xStatorToothTangOuterMir,yStatorToothTangOuterMir] = coordinateTransformation(app, rStatorToothTangOuter,alphaStatorToothTangOuterMir,'polar2rec');
                line(app.laminationStructurePlot,[xStatorToothTangOuter,xStatorToothTangInner], [yStatorToothTangOuter,yStatorToothTangInner], "LineWidth",2,"Color","k","LineStyle","-");
                line(app.laminationStructurePlot,[xStatorToothTangOuterMir,xStatorToothTangInnerMir], [yStatorToothTangOuterMir,yStatorToothTangInnerMir], "LineWidth",2,"Color","k","LineStyle","-");
                line(app.laminationStructurePlot,[xStatorToothTangOuter,xStatorToothInner],[yStatorToothTangOuter,yStatorToothInner], "LineWidth",2,"Color","k","LineStyle","-");
                line(app.laminationStructurePlot,[xStatorToothTangOuterMir,xStatorToothInnerMir],[yStatorToothTangOuterMir,yStatorToothInnerMir], "LineWidth",2,"Color","k","LineStyle","-");
                alphaStatorToothTangOuter = alphaStatorToothTangOuter + 2*pi/app.statorNumOfSlots.Value;
                alphaStatorToothTangOuterMir = alphaStatorToothTangOuterMir + 2*pi/app.statorNumOfSlots.Value;
                alphaStatorToothTangInner = alphaStatorToothTangInner + 2*pi/app.statorNumOfSlots.Value;
                alphaStatorToothTangInnerMir = alphaStatorToothTangInnerMir + 2*pi/app.statorNumOfSlots.Value;
            end
            %%绘制冲片视图转子轭
            alphaRotorYokeOuter = 0:pi/200:2*pi;
            rRotorYokeOuter = zeros(1,length(alphaRotorYokeOuter)) + app.rotorOuterDiameter.Value/2-app.rotorMagnetThickness.Value;
            [xRotorYokeOuter,yRotorYokeOuter] = coordinateTransformation (app, rRotorYokeOuter, alphaRotorYokeOuter, 'polar2rec');
            plot(app.laminationStructurePlot, xRotorYokeOuter, yRotorYokeOuter, "LineWidth", 2, "Color", "k", "LineStyle", "-");
            alphaRotorYokeInner = 0:pi/200:2*pi;
            rRotorYokeInner = zeros(1,length(alphaRotorYokeInner)) + app.rotorYokeInnerDiameter.Value/2;
            [xRotorYokeInner,yRotorYokeInner] = coordinateTransformation (app, rRotorYokeInner, alphaRotorYokeInner, 'polar2rec');
            plot(app.laminationStructurePlot, xRotorYokeInner, yRotorYokeInner, "LineWidth", 2, "Color", "k", "LineStyle","-");
            %%绘制冲片视图磁钢
            rRotorMagnetOuterArcGuide = zeros(1,201) + app.rotorMagnetOuterArcRadius.Value;
            thetaRotorMagnetOutArc = asin((app.rotorMagnetWidth.Value/2)/(app.rotorMagnetOuterArcRadius.Value));
            alphaRotorMagnetOuterArcGuide = -thetaRotorMagnetOutArc:thetaRotorMagnetOutArc/100:thetaRotorMagnetOutArc;
            [xRotorMagnetOuterArcGuide,yRotorMagnetOuterArc] = coordinateTransformation (app, rRotorMagnetOuterArcGuide, alphaRotorMagnetOuterArcGuide, 'polar2rec');
            xRotorMagnetOuterArc = xRotorMagnetOuterArcGuide + app.rotorOuterDiameter.Value/2 - app.rotorMagnetOuterArcRadius.Value;
            [rRotorMagnetOuterArc,alphaRotorMagnetOuterArc] = coordinateTransformation (app, xRotorMagnetOuterArc, yRotorMagnetOuterArc, 'rec2polar');
            rRotorMagnetInner = app.rotorOuterDiameter.Value/2-app.rotorMagnetThickness.Value;
            alphaRotorMagnetInner = asin((app.rotorMagnetWidth.Value/2)/rRotorMagnetInner);
            alphaRotorMagnetInnerMir = -alphaRotorMagnetInner;
            for j=1:1:app.rotorNumOfPoles.Value
                [xRotorMagnetOuterArc,yRotorMagnetOuterArc] = coordinateTransformation (app, rRotorMagnetOuterArc, alphaRotorMagnetOuterArc, 'polar2rec');
                plot(app.laminationStructurePlot,xRotorMagnetOuterArc,yRotorMagnetOuterArc, "LineWidth", 2, "Color", "k", "LineStyle","-");
                alphaRotorMagnetOuterArc = alphaRotorMagnetOuterArc + 2*pi/app.rotorNumOfPoles.Value;
                [xRotorMagnetInner,yRotorMagnetInner] = coordinateTransformation(app, rRotorMagnetInner, alphaRotorMagnetInner, 'polar2rec');
                [xRotorMagnetInnerMir,yRotorMagnetInnerMir] = coordinateTransformation(app, rRotorMagnetInner, alphaRotorMagnetInnerMir, 'polar2rec');
                line(app.laminationStructurePlot, [xRotorMagnetInner, xRotorMagnetOuterArc(end)], [yRotorMagnetInner, yRotorMagnetOuterArc(end)], "LineWidth", 2, "Color", "k", "LineStyle","-");
                line(app.laminationStructurePlot, [xRotorMagnetInnerMir, xRotorMagnetOuterArc(1)], [yRotorMagnetInnerMir, yRotorMagnetOuterArc(1)], "LineWidth", 2, "Color", "k", "LineStyle","-");
                alphaRotorMagnetInner = alphaRotorMagnetInner + 2*pi/app.rotorNumOfPoles.Value;
                alphaRotorMagnetInnerMir = alphaRotorMagnetInnerMir + 2*pi/app.rotorNumOfPoles.Value;
            end
            hold(app.laminationStructurePlot,'off');
            clear i j;
            %%绘制轴向视图机械转轴
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.thermalCalculator.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {530, 530};
                app.GridLayout.ColumnWidth = {'1x'};
                app.results.Layout.Row = 2;
                app.results.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {266, '1x'};
                app.results.Layout.Row = 1;
                app.results.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.thermalCalculator = uifigure('Visible', 'off');
            app.thermalCalculator.Name = 'UI Figure';
            app.thermalCalculator.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.thermalCalculator);
            app.parameters = uipanel(app.GridLayout);
            app.parametersTab = uitabgroup(app.parameters);
            app.statorParameters = uitab(app.parametersTab);
            app.statorParameters.Title = 'Stator';
            app.OuterDiaEditFieldLabel = uilabel(app.statorParameters);
            app.OuterDiaEditFieldLabel.Text = 'Outer Dia';
            app.statorOuterDiameter = uieditfield(app.statorParameters, 'numeric');
            app.statorOuterDiameter.Value = 100;
            app.InnerDiaLabel = uilabel(app.statorParameters);
            app.InnerDiaLabel.Text = 'Inner Dia';
            app.statorInnerDiameter = uieditfield(app.statorParameters, 'numeric');
            app.statorInnerDiameter.Value = 50;
            app.StackHeightLabel = uilabel(app.statorParameters);
            app.StackHeightLabel.Text = 'Stack Height';
            app.statorStackHeight = uieditfield(app.statorParameters, 'numeric');
            app.statorStackHeight.Value = 35;
            app.NumberofSlotsEditFieldLabel = uilabel(app.statorParameters);
            app.NumberofSlotsEditFieldLabel.Text = 'Number of Slots';
            app.statorNumOfSlots = uieditfield(app.statorParameters, 'numeric');
            app.statorNumOfSlots.Value = 12;
            app.ToothWidthLabel = uilabel(app.statorParameters);
            app.ToothWidthLabel.Text = 'Tooth Width';
            app.statorToothWidth = uieditfield(app.statorParameters, 'numeric');
            app.statorToothWidth.Value = 8;
            app.SlotOpeningWidthLabel = uilabel(app.statorParameters);
            app.SlotOpeningWidthLabel.Text = 'Slot Opening Width';
            app.statorSlotOpeningWidth = uieditfield(app.statorParameters, 'numeric');
            app.statorSlotOpeningWidth.Value = 2;
            app.YokeWidthLabel = uilabel(app.statorParameters);
            app.YokeWidthLabel.Text = 'Yoke Width';
            app.statorYokeWidth = uieditfield(app.statorParameters, 'numeric');
            app.statorYokeWidth.Value = 8;
            app.ToothShoeDepthEditFieldLabel = uilabel(app.statorParameters);
            app.ToothShoeDepthEditFieldLabel.Text = 'Tooth Shoe Depth';
            app.statorToothShoeDepth = uieditfield(app.statorParameters, 'numeric');
            app.statorToothShoeDepth.Value = 2;
            app.ToothTangHeightLabel = uilabel(app.statorParameters);
            app.ToothTangHeightLabel.Text = 'Tooth Tang Height';
            app.statorToothTangHeight = uieditfield(app.statorParameters, 'numeric');
            app.statorToothTangHeight.Value = 1;
            app.rotorParameters = uitab(app.parametersTab);
            app.rotorParameters.Title = 'Rotor';
            app.OuterDiaEditFieldLabel_2 = uilabel(app.rotorParameters);
            app.OuterDiaEditFieldLabel_2.Text = 'Outer Dia';
            app.rotorOuterDiameter = uieditfield(app.rotorParameters, 'numeric');
            app.rotorOuterDiameter.Value = 48;
            app.InnerDiaLabel_2 = uilabel(app.rotorParameters);
            app.InnerDiaLabel_2.Text = 'Inner Dia';
            app.rotorYokeInnerDiameter = uieditfield(app.rotorParameters, 'numeric');
            app.rotorYokeInnerDiameter.Value = 30;
            app.NumberofPolesLabel = uilabel(app.rotorParameters);
            app.NumberofPolesLabel.Text = 'Number of Poles';
            app.rotorNumOfPoles = uieditfield(app.rotorParameters, 'numeric');
            app.rotorNumOfPoles.Value = 4;
            app.MagnetWidthLabel = uilabel(app.rotorParameters);
            app.MagnetWidthLabel.Text = 'Magnet Width';
            app.rotorMagnetWidth = uieditfield(app.rotorParameters, 'numeric');
            app.rotorMagnetWidth.Value = 28;
            app.MagnetThicknessLabel = uilabel(app.rotorParameters);
            app.MagnetThicknessLabel.Text = 'Magnet Thickness';
            app.rotorMagnetThickness = uieditfield(app.rotorParameters, 'numeric');
            app.rotorMagnetThickness.Value = 3;
            app.MagnetOuterArcRadiusLabel = uilabel(app.rotorParameters);
            app.MagnetOuterArcRadiusLabel.Text = 'Magnet Outer Arc Radius';
            app.rotorMagnetOuterArcRadius = uieditfield(app.rotorParameters, 'numeric');
            app.rotorMagnetOuterArcRadius.Value = 20;
            app.shafting = uitab(app.parametersTab);
            app.shafting.Title = 'Shafting';
            app.OuterDiaEditFieldLabel_3 = uilabel(app.shafting);
            app.OuterDiaEditFieldLabel_3.Text = 'Outer Dia';
            app.rotorOuterDiameter_2 = uieditfield(app.shafting, 'numeric');
            app.rotorOuterDiameter_2.Value = 48;
            app.InnerDiaLabel_3 = uilabel(app.shafting);
            app.InnerDiaLabel_3.Text = 'Inner Dia';
            app.rotorYokeInnerDiameter_2 = uieditfield(app.shafting, 'numeric');
            app.rotorYokeInnerDiameter_2.Value = 30;
            app.NumberofPolesLabel_2 = uilabel(app.shafting);
            app.NumberofPolesLabel_2.Text = 'Number of Poles';
            app.rotorNumOfPoles_2 = uieditfield(app.shafting, 'numeric');
            app.rotorNumOfPoles_2.Value = 4;
            app.MagnetWidthLabel_2 = uilabel(app.shafting);
            app.MagnetWidthLabel_2.Text = 'Magnet Width';
            app.rotorMagnetWidth_2 = uieditfield(app.shafting, 'numeric');
            app.rotorMagnetWidth_2.Value = 28;
            app.MagnetThicknessLabel_2 = uilabel(app.shafting);
            app.MagnetThicknessLabel_2.Text = 'Magnet Thickness';
            app.rotorMagnetThickness_2 = uieditfield(app.shafting, 'numeric');
            app.rotorMagnetThickness_2.Value = 3;
            app.CaseTab = uitab(app.parametersTab);
            app.CaseTab.Title = 'Case';
            app.OuterDiaEditFieldLabel_4 = uilabel(app.CaseTab);
            app.OuterDiaEditFieldLabel_4.Text = 'Outer Dia';
            app.rotorOuterDiameter_3 = uieditfield(app.CaseTab, 'numeric');
            app.rotorOuterDiameter_3.Value = 48;
            app.InnerDiaLabel_4 = uilabel(app.CaseTab);
            app.InnerDiaLabel_4.Text = 'Inner Dia';
            app.rotorYokeInnerDiameter_3 = uieditfield(app.CaseTab, 'numeric');
            app.rotorYokeInnerDiameter_3.Value = 30;
            app.NumberofPolesLabel_3 = uilabel(app.CaseTab);
            app.NumberofPolesLabel_3.Text = 'Number of Poles';
            app.rotorNumOfPoles_3 = uieditfield(app.CaseTab, 'numeric');
            app.rotorNumOfPoles_3.Value = 4;
            app.MagnetWidthLabel_3 = uilabel(app.CaseTab);
            app.MagnetWidthLabel_3.Text = 'Magnet Width';
            app.rotorMagnetWidth_3 = uieditfield(app.CaseTab, 'numeric');
            app.rotorMagnetWidth_3.Value = 28;
            app.MagnetThicknessLabel_3 = uilabel(app.CaseTab);
            app.MagnetThicknessLabel_3.Text = 'Magnet Thickness';
            app.rotorMagnetThickness_3 = uieditfield(app.CaseTab, 'numeric');
            app.rotorMagnetThickness_3.Value = 3;
            app.operationParameters = uitab(app.parametersTab);
            app.operationParameters.Title = 'Operating Parameters';
            app.lossParameters = uitab(app.parametersTab);
            app.lossParameters.Title = 'Loss';
            app.results = uipanel(app.GridLayout);
            app.resultsTab = uitabgroup(app.results);
            app.laminationStructure = uitab(app.resultsTab);
            app.laminationStructure.Title = 'Lamination Structure';
            app.laminationStructurePlot = uiaxes(app.laminationStructure);
            title(app.laminationStructurePlot, '')
            xlabel(app.laminationStructurePlot, '')
            ylabel(app.laminationStructurePlot, '')
            app.axialStructure = uitab(app.resultsTab);
            app.axialStructure.Title = 'Axial Structure';
            app.axialStructurePlot = uiaxes(app.axialStructure);
            title(app.axialStructurePlot, '')
            xlabel(app.axialStructurePlot, '')
            ylabel(app.axialStructurePlot, '')
            app.thermalResistance = uitab(app.resultsTab);
            app.thermalResistance.Title = 'Thermal Resistance';
            app.ApplyParameters = uibutton(app.results, 'push');
            app.ApplyParameters.ButtonPushedFcn = createCallbackFcn(app, @ApplyParametersButtonPushed, true);
            app.ApplyParameters.Text = 'Apply Parameters';
            app.Run = uibutton(app.results, 'push');
            app.Run.Text = 'Run';
            % Show the figure after all components are created
            app.thermalCalculator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThermalCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.thermalCalculator)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.thermalCalculator)
        end
    end
end